classdef Pole_placement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        uipanel2                   matlab.ui.container.Panel
        SystemorderDropDownLabel   matlab.ui.control.Label
        SystemorderDropDown        matlab.ui.control.DropDown
        ParametersPanel_2          matlab.ui.container.Panel
        KEditField_2Label          matlab.ui.control.Label
        KEditField                 matlab.ui.control.NumericEditField
        T1EditFieldLabel           matlab.ui.control.Label
        T1EditField                matlab.ui.control.NumericEditField
        T2EditFieldLabel           matlab.ui.control.Label
        T2EditField                matlab.ui.control.NumericEditField
        panel_4                    matlab.ui.container.Panel
        text_k_10                  matlab.ui.control.Label
        text_k_11                  matlab.ui.control.Label
        uipanel4_4                 matlab.ui.container.Panel
        uipanel5_4                 matlab.ui.container.Panel
        text_k_12                  matlab.ui.control.Label
        ControllerPanel            matlab.ui.container.Panel
        ControllerDropDownLabel    matlab.ui.control.Label
        ControllerDropDown         matlab.ui.control.DropDown
        ParametersPanel            matlab.ui.container.Panel
        CEditFieldLabel            matlab.ui.control.Label
        CEditField                 matlab.ui.control.NumericEditField
        TiEditFieldLabel           matlab.ui.control.Label
        TiEditField                matlab.ui.control.NumericEditField
        TdEditFieldLabel           matlab.ui.control.Label
        TdEditField                matlab.ui.control.NumericEditField
        panel_3                    matlab.ui.container.Panel
        uipanel4_3                 matlab.ui.container.Panel
        text_k_13                  matlab.ui.control.Label
        uipanel5_5                 matlab.ui.container.Panel
        text_k_15                  matlab.ui.control.Label
        text_k_14                  matlab.ui.control.Label
        DesiredPerformancesPanel   matlab.ui.container.Panel
        TimecriteriaDropDownLabel  matlab.ui.control.Label
        TimecriteriaDropDown       matlab.ui.control.DropDown
        StaticgainEditFieldLabel   matlab.ui.control.Label
        StaticgainEditField        matlab.ui.control.NumericEditField
        OvershootEditFieldLabel    matlab.ui.control.Label
        OvershootEditField         matlab.ui.control.NumericEditField
        sEditFieldLabel            matlab.ui.control.Label
        TimeEditField              matlab.ui.control.NumericEditField
        CheckBox                   matlab.ui.control.CheckBox
        CheckBox_2                 matlab.ui.control.CheckBox
        CheckBox_3                 matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        system % Description
        controller % Description
    end
    
    methods (Access = private)
        
        function [K, T1, T2] = get_system_data(app)
            K = app.system.K;
            T1 = app.system.T1;
            T2 = app.system.T2;
        end
        
        function [Kdes, Tdes, Ddes] = get_desired(app)
            Kdes = app.StaticgainEditField.Value;
            Tdes = app.TimeEditField.Value;
            Ddes = app.OvershootEditField.Value;
        end
        
        function update_system(app)
            K = app.KEditField.Value;
            T1 = app.T1EditField.Value;
            T2 = app.T2EditField.Value;
            
            app.system.K = K;
            app.system.T1 = T1;
            if strcmp(app.SystemorderDropDown.Value, '1st')
                app.system.T2 = 0;
            else
                app.system.T2 = T2;
            end
            app.system.sys = tf(K,[T1 1])*tf(1,[T2 1]);
        end
        
        function update_controller(app)
            C = app.CEditField.Value;
            Ti = app.TiEditField.Value;
            Td = app.TdEditField.Value;

            app.controller.C = C;
            app.controller.Ti = Ti;
            app.controller.Td = Td;

            if strcmp(app.ControllerDropDown.Value, 'P')
                ctr = C;
            elseif strcmp(app.ControllerDropDown.Value, 'PI')
                ctr = C*tf([Ti 1], [Ti 0]);
            else
                ctr = C*tf([Ti*Td Ti+Td 1], [Ti 0]);
            end
            app.controller.ctr = ctr;
        end
        function update_figure(app)
            [K, T1, T2] = get_system_data(app);
            [Kdes, Tdes, Ddes] = get_desired(app);
            
            if strcmp(app.SystemorderDropDown.Value, '1st')
                
            end
        end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Closed Loop Step Response')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, '')
            app.uipanel2 = uipanel(app.UIFigure);
            app.uipanel2.Title = 'System';
            app.SystemorderDropDownLabel = uilabel(app.uipanel2);
            app.SystemorderDropDownLabel.Text = 'System order';
            app.SystemorderDropDown = uidropdown(app.uipanel2);
            app.SystemorderDropDown.Items = {'1st', '2nd'};
            app.SystemorderDropDown.Value = '1st';
            app.ParametersPanel_2 = uipanel(app.uipanel2);
            app.ParametersPanel_2.Title = 'Parameters';
            app.KEditField_2Label = uilabel(app.ParametersPanel_2);
            app.KEditField_2Label.Text = 'K';
            app.KEditField = uieditfield(app.ParametersPanel_2, 'numeric');
            app.KEditField.Editable = 'off';
            app.T1EditFieldLabel = uilabel(app.ParametersPanel_2);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.ParametersPanel_2, 'numeric');
            app.T1EditField.Editable = 'off';
            app.T2EditFieldLabel = uilabel(app.ParametersPanel_2);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.ParametersPanel_2, 'numeric');
            app.T2EditField.Editable = 'off';
            app.panel_4 = uipanel(app.uipanel2);
            app.panel_4.Title = 'Transfert Function';
            app.text_k_10 = uilabel(app.panel_4);
            app.text_k_10.Text = 'K';
            app.text_k_11 = uilabel(app.panel_4);
            app.text_k_11.Text = 'G(p) = ';
            app.uipanel4_4 = uipanel(app.panel_4);
            app.uipanel5_4 = uipanel(app.panel_4);
            app.text_k_12 = uilabel(app.panel_4);
            app.text_k_12.Text = '(1 + T1 · p) (1 + T2 · p)';
            app.ControllerPanel = uipanel(app.UIFigure);
            app.ControllerPanel.Title = 'Controller';
            app.ControllerDropDownLabel = uilabel(app.ControllerPanel);
            app.ControllerDropDownLabel.Text = 'Controller';
            app.ControllerDropDown = uidropdown(app.ControllerPanel);
            app.ControllerDropDown.Items = {'P', 'PI', 'PID'};
            app.ControllerDropDown.Value = 'P';
            app.ParametersPanel = uipanel(app.ControllerPanel);
            app.ParametersPanel.Title = 'Parameters';
            app.CEditFieldLabel = uilabel(app.ParametersPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.CEditField.Editable = 'off';
            app.TiEditFieldLabel = uilabel(app.ParametersPanel);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.TiEditField.Editable = 'off';
            app.TdEditFieldLabel = uilabel(app.ParametersPanel);
            app.TdEditFieldLabel.Text = 'Td';
            app.TdEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.TdEditField.Editable = 'off';
            app.panel_3 = uipanel(app.ControllerPanel);
            app.panel_3.Title = 'Transfert Function';
            app.uipanel4_3 = uipanel(app.panel_3);
            app.text_k_13 = uilabel(app.panel_3);
            app.text_k_13.Text = 'C(p) = C ';
            app.uipanel5_5 = uipanel(app.panel_3);
            app.text_k_15 = uilabel(app.uipanel5_5);
            app.text_k_15.Text = 'Ti p';
            app.text_k_14 = uilabel(app.panel_3);
            app.text_k_14.Text = '(1 + Ti · p) (1 + Td · p)';
            app.DesiredPerformancesPanel = uipanel(app.UIFigure);
            app.DesiredPerformancesPanel.Title = 'Desired Performances';
            app.TimecriteriaDropDownLabel = uilabel(app.DesiredPerformancesPanel);
            app.TimecriteriaDropDownLabel.Text = 'Time criteria';
            app.TimecriteriaDropDown = uidropdown(app.DesiredPerformancesPanel);
            app.TimecriteriaDropDown.Items = {'Settling time', 'Rise time'};
            app.TimecriteriaDropDown.Value = 'Settling time';
            app.StaticgainEditFieldLabel = uilabel(app.DesiredPerformancesPanel);
            app.StaticgainEditFieldLabel.Text = 'Static gain';
            app.StaticgainEditField = uieditfield(app.DesiredPerformancesPanel, 'numeric');
            app.OvershootEditFieldLabel = uilabel(app.DesiredPerformancesPanel);
            app.OvershootEditFieldLabel.Text = 'Overshoot';
            app.OvershootEditField = uieditfield(app.DesiredPerformancesPanel, 'numeric');
            app.sEditFieldLabel = uilabel(app.DesiredPerformancesPanel);
            app.sEditFieldLabel.Text = '(s)';
            app.TimeEditField = uieditfield(app.DesiredPerformancesPanel, 'numeric');
            app.CheckBox = uicheckbox(app.DesiredPerformancesPanel);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.DesiredPerformancesPanel);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.DesiredPerformancesPanel);
            app.CheckBox_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pole_placement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end